classdef ellipsometry_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        LeftPanel                    matlab.ui.container.Panel
        RunanalysisButton            matlab.ui.control.Button
        DirectYLimitEditField        matlab.ui.control.NumericEditField
        DirectYMaxLabel              matlab.ui.control.Label
        FolderStatusLabel            matlab.ui.control.Label
        WavelengthSlider             matlab.ui.control.RangeSlider
        Wavelength2501000nmLabel     matlab.ui.control.Label
        TempRangeSlider9020C         matlab.ui.control.RangeSlider
        TempRangeSlider9020CLabel    matlab.ui.control.Label
        Egsearch135170eVSlider       matlab.ui.control.RangeSlider
        Egsearch135170eVSliderLabel  matlab.ui.control.Label
        BandgaptypeButtonGroup       matlab.ui.container.ButtonGroup
        IndirectButton               matlab.ui.control.RadioButton
        DirectButton                 matlab.ui.control.RadioButton
        Lamp                         matlab.ui.control.Lamp
        LoadWVASEDataButton          matlab.ui.control.Button
        RightPanel                   matlab.ui.container.Panel
        TabGroup                     matlab.ui.container.TabGroup
        OpticalConstantsTab          matlab.ui.container.Tab
        GridLayout2                  matlab.ui.container.GridLayout
        WelcomePanel                 matlab.ui.container.Panel
        RunanalysisLabel             matlab.ui.control.Label
        ChooseconstraintsLabel       matlab.ui.control.Label
        LoadDatafolderLabel          matlab.ui.control.Label
        WelcometolowtemperatureellipsometryanalyserLabel  matlab.ui.control.Label
        UIAxes_Legend                matlab.ui.control.UIAxes
        UIAxes_n                     matlab.ui.control.UIAxes
        UIAxes_k                     matlab.ui.control.UIAxes
        UIAxes_alphaLin              matlab.ui.control.UIAxes
        UIAxes_alphaLog              matlab.ui.control.UIAxes
        TaucAnalysisTab              matlab.ui.container.Tab
        UIAxes_Tauc                  matlab.ui.control.UIAxes
        ResultsSystemNoiseTab        matlab.ui.container.Tab
        TempDropDown                 matlab.ui.control.DropDown
        DropDownLabel                matlab.ui.control.Label
        UIAxes_TaucSingle            matlab.ui.control.UIAxes
        UIAxes_Eg                    matlab.ui.control.UIAxes
        NoiseAnalysisTab             matlab.ui.container.Tab
        UIAxes_PsiTrend              matlab.ui.control.UIAxes
        UIAxes_PsiOverlay            matlab.ui.control.UIAxes
        UIAxes_DeltaTrend            matlab.ui.control.UIAxes
        UIAxes_DeltaOverlay          matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        DataPath       % Stores the path to the data folder
        ExtractedEg    % Stores calculated Bandgap values
        NoiseDelta     % Stores calculated RMS noise values
        TempsC         % Stores the temperature array
        SavedOpticalData % Fast memory for Live Slider
        % Cache memory to speed up live sliders
        CachedOptData    % Stores pre-loaded optical constants for all temperatures
        CachedNoiseData  % Stores the pre-loaded master noise cell array
        IsDataLoaded = false % Flag to track if caching is complete
        GraphHandles = struct() % Graphic handles library for ultra-fast rendering
        % Live tracking bounds for real-time slider dragging
        LiveWavelengthRange          % Holds intermediate wavelength limits during a drag
        LiveEgSearchRange            % Holds intermediate energy search limits during a drag
    end
    
    methods (Access = private)
        
        function [L, n, k, a, E] = extract_opt(~, filename)
            data = readtable(filename, 'VariableNamingRule', 'preserve'); 
            raw = data{:, :};
            if iscell(raw)
                proc = nan(size(raw));
                for r=1:size(raw,1)
                    for c=1:size(raw,2)
                        if isnumeric(raw{r,c})
                            proc(r,c)=raw{r,c};
                        else
                            proc(r,c)=str2double(string(raw{r,c}));
                        end
                    end 
                end
                raw = proc;
            end
            L = raw(:, 1); n = raw(:, 2); k = raw(:, 3); a = raw(:, 4); E = raw(:, 5);
        end

        function [L, psi, delta] = extract_ellip(~, filename)
            data = readtable(filename, 'VariableNamingRule', 'preserve'); 
            raw = data{:, :};
            if iscell(raw)
                proc = nan(size(raw));
                for r=1:size(raw,1)
                    for c=1:size(raw,2)
                        if isnumeric(raw{r,c})
                            proc(r,c)=raw{r,c};
                        else 
                            proc(r,c)=str2double(string(raw{r,c}));
                        end
                    end
                end
                raw = proc;
            end
            L = raw(:, 1); psi = raw(:, 3); delta = raw(:, 4); 
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadWVASEDataButton
        function ButtonPushedFcn(app, event)
            % Open directory selector starting at current path
            folder_name = uigetdir(pwd, 'Select WVASE Data Folder');
            
            if isequal(folder_name, 0)
                return; 
            end
            app.DataPath = folder_name;
            
            % --- CREATE PROFESSIONAL PROGRESS DIALOG ---
            d = uiprogressdlg(app.UIFigure, 'Title', 'Loading Data to Cache', ...
                'Message', 'Reading master noise file...', 'Cancelable', 'off');
            
            % 1. Pre-load Master Noise File
            noise_search = dir(fullfile(app.DataPath, '**', '*raw_data*.xlsx'));
            if ~isempty(noise_search)
                noise_file = fullfile(noise_search(1).folder, noise_search(1).name);
                app.CachedNoiseData = readcell(noise_file);
            end
            
            % 2. Pre-load temperature files
            temp_sweep = 20:-5:-110; 
            app.CachedOptData = struct(); 
            total_files = length(temp_sweep);
            
            for k = 1:total_files
                T = temp_sweep(k);
                
                % --- UPDATE PROGRESS BAR LIVE ---
                d.Value = (k / total_files) * 0.95; % Go up to 95% during loop
                d.Message = sprintf('Caching temperature profile: %d °C...', T);
                
                if T >= 0, t_str = sprintf('_%dc', T); 
                else, t_str = sprintf('_m%dc', abs(T)); end
                
                found = dir(fullfile(app.DataPath, ['*' t_str '*.xlsx']));
                opt_file = '';
                for m = 1:length(found)
                    if startsWith(found(m).name, '~$'), continue; end 
                    if ~contains(found(m).name, '_data'), opt_file = fullfile(app.DataPath, found(m).name); end
                end
                
                if ~isempty(opt_file)
                    [L_raw, n_raw, k_raw, a_raw, E_raw] = app.extract_opt(opt_file);
                    f_name = sprintf('T_%s', strrep(t_str, '_', ''));
                    app.CachedOptData.(f_name).L_opt_raw = L_raw;
                    app.CachedOptData.(f_name).n_idx_raw = n_raw;
                    app.CachedOptData.(f_name).k_ext_raw = k_raw;
                    app.CachedOptData.(f_name).alpha_raw = a_raw;
                    app.CachedOptData.(f_name).E_ev_raw = E_raw;
                end
            end
            
            app.IsDataLoaded = true;
            
            % RESET GRAPHICS FLAG TO PREVENT LIVE PLOTTING BEFORE FIRST MANUAL RUN
            app.GraphHandles = struct();

            % --- TURN LAMP GREEN ---
            app.Lamp.Color = [0, 1, 0];

            % Update labels
            [~, last_folder, ~] = fileparts(app.DataPath);
            app.FolderStatusLabel.Text = sprintf('Active: \\%s', last_folder);
            app.FolderStatusLabel.FontColor = [0, 0.5, 0];
            
            % Finish and close progress dialog gracefully
            d.Value = 1;
            d.Message = 'Caching Complete!';
            pause(0.3); % Brief pause so the user sees 100%
            close(d);
        end

        % Button pushed function: RunanalysisButton
        function RunanalysisButtonPushed(app, event)
            if isempty(app.DataPath)
                uialert(app.UIFigure, 'Please load a data folder first.', 'Missing Data');
                return;
            end
            
            app.WelcomePanel.Visible = 'off';
            
            % Initialize live tracking parameters if empty to prevent startup lag
            if isempty(app.LiveWavelengthRange)
                app.LiveWavelengthRange = app.WavelengthSlider.Value;
            end
            if isempty(app.LiveEgSearchRange)
                app.LiveEgSearchRange = app.Egsearch135170eVSlider.Value;
            end
            
            % Retrieve User constraints from live tracking properties instead of static components
            min_E = app.LiveEgSearchRange(1); 
            max_E = app.LiveEgSearchRange(2);
            smooth_win = 5;
            wl_min = app.LiveWavelengthRange(1);
            wl_max = app.LiveWavelengthRange(2);
            
            % Process chosen Transition Matrix Elements
            if strcmp(app.BandgaptypeButtonGroup.SelectedObject.Text, 'Direct')
                power_val = 2;
            else
                power_val = 0.5;
            end
            
            min_T = round(app.TempRangeSlider9020C.Value(1) / 5) * 5; 
            max_T = round(app.TempRangeSlider9020C.Value(2) / 5) * 5; 
            temps_C = max_T:-5:min_T; 
            
            has_noise = ~isempty(app.CachedNoiseData);
            if has_noise, rawDataCell = app.CachedNoiseData; [numRows, numCols] = size(rawDataCell); end
            
            N = length(temps_C);
            if N == 0, return; end 
            
            cmap = flipud(jet(N)); 
            extracted_Eg = nan(1, N);
            noise_metric_delta = nan(1, N);
            noise_metric_psi = nan(1, N);
            
            % --- SMART RE-INITIALIZATION TRIGGER ---
            % (This belongs OUTSIDE the loop!)
            needs_reinit = ~isfield(app.GraphHandles, 'Initialized') || ...
                           isempty(app.GraphHandles.alphaLin) || ...
                           length(app.GraphHandles.alphaLin) ~= N;
            
            if needs_reinit
                % 🚨 THE ULTIMATE FIX: 'allchild' forces MATLAB to grab hidden objects too!
                delete(allchild(app.UIAxes_alphaLin)); delete(allchild(app.UIAxes_alphaLog));
                delete(allchild(app.UIAxes_n));        delete(allchild(app.UIAxes_k));
                delete(allchild(app.UIAxes_Tauc));     delete(allchild(app.UIAxes_Eg)); 
                delete(allchild(app.UIAxes_DeltaOverlay)); delete(allchild(app.UIAxes_DeltaTrend));
                delete(allchild(app.UIAxes_PsiOverlay));   delete(allchild(app.UIAxes_PsiTrend));
                delete(allchild(app.UIAxes_Legend));
                
                app.GraphHandles.alphaLin = cell(1, N); app.GraphHandles.alphaLog = cell(1, N);
                app.GraphHandles.n_idx = cell(1, N);    app.GraphHandles.k_ext = cell(1, N);
                app.GraphHandles.tauc_line = cell(1, N); app.GraphHandles.tauc_fit = cell(1, N);
                app.GraphHandles.tauc_mark = cell(1, N);
                app.GraphHandles.noise_d = cell(1, N);   app.GraphHandles.noise_p = cell(1, N);
            end
            
            app.SavedOpticalData = []; 
            
            % 4. Main Sweep Loop
            for i = 1:N
                T = temps_C(i);
                if T >= 0, t_str = sprintf('_%dc', T); 
                else, t_str = sprintf('_m%dc', abs(T)); end
                
                f_name = sprintf('T_%s', strrep(t_str, '_', ''));
                
                % 🚨 THE SECOND FIX: Reset ALL plotting arrays to NaN INSIDE the loop
                L_opt=NaN; n_idx=NaN; k_ext=NaN; alpha=NaN; E_ev=NaN; 
                y_tauc=NaN; x_ext=nan(1,50); y_ext=nan(1,50); Eg=NaN;
                
                if isfield(app.CachedOptData, f_name)
                    L_opt_raw = app.CachedOptData.(f_name).L_opt_raw;
                    n_idx_raw = app.CachedOptData.(f_name).n_idx_raw;
                    k_ext_raw = app.CachedOptData.(f_name).k_ext_raw;
                    alpha_raw = app.CachedOptData.(f_name).alpha_raw;
                    E_ev_raw  = app.CachedOptData.(f_name).E_ev_raw;
                    
                    valid_opt_mask = (L_opt_raw >= wl_min) & (L_opt_raw <= wl_max);
                    
                    if any(valid_opt_mask)
                        L_opt = L_opt_raw(valid_opt_mask);
                        n_idx = n_idx_raw(valid_opt_mask);
                        k_ext = k_ext_raw(valid_opt_mask);
                        alpha = alpha_raw(valid_opt_mask);
                        E_ev  = E_ev_raw(valid_opt_mask);
                        
                        opt_data.E_ev = E_ev; opt_data.alpha = alpha;
                        opt_data.power_val = power_val; opt_data.color = cmap(i,:);
                        app.SavedOpticalData = [app.SavedOpticalData, opt_data];
                        
                        if length(E_ev) > 5
                            y_tauc = real((max(alpha, 0) .* E_ev).^power_val);
                            deriv = diff(smoothdata(y_tauc, 'gaussian', smooth_win)) ./ diff(E_ev);
                            E_mid = (E_ev(1:end-1) + E_ev(2:end)) / 2;
                            search_mask = (E_mid > min_E & E_mid < max_E); 
                            
                            if any(search_mask)
                                [~, max_idx_rel] = max(deriv(search_mask));
                                search_indices = find(search_mask);
                                center_idx = search_indices(max_idx_rel);
                                fit_win = (center_idx - 8) : (center_idx + 8);
                                fit_win = fit_win(fit_win > 0 & fit_win <= length(E_ev));
                                
                                if length(fit_win) >= 2
                                    p = polyfit(E_ev(fit_win), y_tauc(fit_win), 1);
                                    Eg = -p(2) / p(1);
                                    extracted_Eg(i) = Eg;
                                    x_ext = linspace(Eg, E_ev(center_idx) + 0.05, 50);
                                    y_ext = polyval(p, x_ext);
                                end
                            end
                        end
                    end
                end 
                
                % --- NOISE EVALUATION ---
                wl_filt = NaN; r_d = NaN; r_p = NaN;
                if has_noise
                    block_idx = round((20 - T) / 5) + 1;
                    col_start = (block_idx - 1) * 5 + 1;
                    if col_start + 4 <= numCols
                        wl_col = rawDataCell(:, col_start + 1);
                        psi_col = rawDataCell(:, col_start + 3);
                        delta_col = rawDataCell(:, col_start + 4);
                        
                        wl_raw = []; psi_raw = []; delta_raw = [];
                        for k = 1:numRows
                            v_wl = wl_col{k}; v_p = psi_col{k}; v_d = delta_col{k};
                            if isnumeric(v_wl) && isnumeric(v_p) && isnumeric(v_d)
                                wl_raw(end+1,1) = v_wl; psi_raw(end+1,1) = v_p; delta_raw(end+1,1) = v_d;
                            end
                        end
                        
                        valid_idx = (wl_raw >= wl_min) & (wl_raw <= wl_max);
                        if sum(valid_idx) > 10
                            wl_filt = wl_raw(valid_idx);
                            d_unwrapped = unwrap(delta_raw(valid_idx) * pi / 180) * 180 / pi;
                            r_d = d_unwrapped - smoothdata(d_unwrapped, 'sgolay', 45); 
                            noise_metric_delta(i) = rms(r_d);
                            
                            psi_filt = psi_raw(valid_idx);
                            r_p = psi_filt - smoothdata(psi_filt, 'sgolay', 45); 
                            noise_metric_psi(i) = rms(r_p);
                        end
                    end
                end
                
                % --- GRAPHICS HANDLING (RESTORED) ---
                if needs_reinit
                    hold(app.UIAxes_alphaLin, 'on'); app.GraphHandles.alphaLin{i} = plot(app.UIAxes_alphaLin, L_opt, alpha, 'LineWidth', 1.2, 'Color', cmap(i,:));
                    hold(app.UIAxes_alphaLog, 'on'); app.GraphHandles.alphaLog{i} = plot(app.UIAxes_alphaLog, E_ev, max(alpha, 1), 'LineWidth', 1.2, 'Color', cmap(i,:));
                    hold(app.UIAxes_n, 'on');        app.GraphHandles.n_idx{i} = plot(app.UIAxes_n, L_opt, n_idx, 'LineWidth', 1.2, 'Color', cmap(i,:));
                    hold(app.UIAxes_k, 'on');        app.GraphHandles.k_ext{i} = plot(app.UIAxes_k, L_opt, k_ext, 'LineWidth', 1.2, 'Color', cmap(i,:));
                    
                    hold(app.UIAxes_Tauc, 'on');
                    app.GraphHandles.tauc_line{i} = plot(app.UIAxes_Tauc, E_ev, y_tauc, 'LineWidth', 1.5, 'Color', cmap(i,:));
                    app.GraphHandles.tauc_fit{i}  = plot(app.UIAxes_Tauc, x_ext, y_ext, '--', 'Color', cmap(i,:), 'HandleVisibility', 'off');
                    app.GraphHandles.tauc_mark{i} = plot(app.UIAxes_Tauc, Eg, 0, 'x', 'Color', cmap(i,:), 'MarkerSize', 8, 'HandleVisibility', 'off');
                    
                    hold(app.UIAxes_DeltaOverlay, 'on'); app.GraphHandles.noise_d{i} = plot(app.UIAxes_DeltaOverlay, wl_filt, r_d, 'Color', cmap(i,:));
                    hold(app.UIAxes_PsiOverlay, 'on');   app.GraphHandles.noise_p{i} = plot(app.UIAxes_PsiOverlay, wl_filt, r_p, 'Color', cmap(i,:));
                else
                    set(app.GraphHandles.alphaLin{i}, 'XData', L_opt, 'YData', alpha);
                    set(app.GraphHandles.alphaLog{i}, 'XData', E_ev, 'YData', max(alpha, 1));
                    set(app.GraphHandles.n_idx{i}, 'XData', L_opt, 'YData', n_idx);
                    set(app.GraphHandles.k_ext{i}, 'XData', L_opt, 'YData', k_ext);
                    
                    set(app.GraphHandles.tauc_line{i}, 'XData', E_ev, 'YData', y_tauc);
                    set(app.GraphHandles.tauc_fit{i}, 'XData', x_ext, 'YData', y_ext);
                    set(app.GraphHandles.tauc_mark{i}, 'XData', Eg, 'YData', 0);
                    
                    set(app.GraphHandles.noise_d{i}, 'XData', wl_filt, 'YData', r_d);
                    set(app.GraphHandles.noise_p{i}, 'XData', wl_filt, 'YData', r_p);
                end
            end
            
            % 5. Global Models & Charts
            T_range_K = linspace(25, -115, 200) + 273.15;
            Eg_intrinsic = 1.519 - (5.405e-4 .* T_range_K.^2) ./ (T_range_K + 204);
            
            n_doping = 3.8e18; m_e = 0.067; m_hh = 0.51; 
            m_vc_star = (m_e * m_hh) / (m_e + m_hh) * 9.11e-31; hbar = 1.054e-34; q = 1.602e-19;
            Delta_BM = ((hbar^2 / (2 * m_vc_star)) * (3 * pi^2 * n_doping * 1e6)^(2/3)) / q; 
            Delta_BGN = (62 * (n_doping / 1e18)^(1/3) + 7.4 * (n_doping / 1e18)^(1/4)) / 1000; 
            Eg_doped = Eg_intrinsic + Delta_BM - Delta_BGN;
            
            if needs_reinit
                hold(app.UIAxes_Eg, 'on');
                app.GraphHandles.eg_int = plot(app.UIAxes_Eg, T_range_K-273.15, Eg_intrinsic, '--k', 'LineWidth', 1.5, 'DisplayName', 'Intrinsic Model');
                app.GraphHandles.eg_dop = plot(app.UIAxes_Eg, T_range_K-273.15, Eg_doped, '-k', 'LineWidth', 2, 'DisplayName', sprintf('Doped (N_d=%.1e)', n_doping));
                app.GraphHandles.eg_meas = plot(app.UIAxes_Eg, temps_C, extracted_Eg, 'ro', 'MarkerFaceColor', 'r', 'MarkerSize', 8, 'DisplayName', 'Extracted E_g');
                legend(app.UIAxes_Eg, 'Location', 'best', 'FontSize', 10);
                
                hold(app.UIAxes_DeltaTrend, 'on'); app.GraphHandles.bar_d = bar(app.UIAxes_DeltaTrend, temps_C, noise_metric_delta, 'FaceColor', 'flat');
                hold(app.UIAxes_PsiTrend, 'on');   app.GraphHandles.bar_p = bar(app.UIAxes_PsiTrend, temps_C, noise_metric_psi, 'FaceColor', 'flat');
                app.GraphHandles.bar_d.CData = flipud(cmap); app.GraphHandles.bar_p.CData = flipud(cmap);
                
                % --- LEGEND CREATION ---
                hold(app.UIAxes_Legend, 'on');
                app.UIAxes_Legend.Visible = 'off';
                for i = 1:N
                    plot(app.UIAxes_Legend, NaN, NaN, 'LineWidth', 1.5, 'Color', cmap(i,:));
                end
                temp_labels = arrayfun(@(t) sprintf('%d °C', t), temps_C, 'UniformOutput', false);
                legend(app.UIAxes_Legend, temp_labels, 'Location', 'best', 'FontSize', 9);
                
                % Axis Labels Setup
                set(app.UIAxes_alphaLog, 'YScale', 'log');
                grid(app.UIAxes_alphaLin, 'on'); grid(app.UIAxes_alphaLog, 'on');
                grid(app.UIAxes_n, 'on'); grid(app.UIAxes_k, 'on');
                grid(app.UIAxes_Tauc, 'on'); grid(app.UIAxes_Eg, 'on');
                grid(app.UIAxes_DeltaOverlay, 'on'); grid(app.UIAxes_DeltaTrend, 'on');
                grid(app.UIAxes_PsiOverlay, 'on'); grid(app.UIAxes_PsiTrend, 'on');
                
                title(app.UIAxes_alphaLin, 'Absorption vs Wavelength'); xlabel(app.UIAxes_alphaLin, 'Wavelength (nm)'); ylabel(app.UIAxes_alphaLin, '\alpha (cm^{-1})');
                title(app.UIAxes_alphaLog, 'Absorption (Log Scale)'); xlabel(app.UIAxes_alphaLog, 'Energy (eV)'); ylabel(app.UIAxes_alphaLog, '\alpha (cm^{-1})');
                title(app.UIAxes_n, 'Refractive Index (n)'); xlabel(app.UIAxes_n, 'Wavelength (nm)'); ylabel(app.UIAxes_n, 'n');
                title(app.UIAxes_k, 'Extinction Coefficient (k)'); xlabel(app.UIAxes_k, 'Wavelength (nm)'); ylabel(app.UIAxes_k, 'k');
                
                title(app.UIAxes_Tauc, 'Tauc Plot Analysis', 'FontSize', 14); xlabel(app.UIAxes_Tauc, 'Energy (eV)');
                title(app.UIAxes_Eg, 'Bandgap vs. Temperature'); xlabel(app.UIAxes_Eg, 'Temperature (°C)'); ylabel(app.UIAxes_Eg, 'E_g (eV)');
                title(app.UIAxes_DeltaOverlay, 'Delta Spectra Overlay'); xlabel(app.UIAxes_DeltaOverlay, 'Wavelength (nm)');
                title(app.UIAxes_DeltaTrend, 'Delta Noise Trend'); xlabel(app.UIAxes_DeltaTrend, 'Temperature (°C)');
                title(app.UIAxes_PsiOverlay, 'Psi Spectra Overlay'); xlabel(app.UIAxes_PsiOverlay, 'Wavelength (nm)');
                title(app.UIAxes_PsiTrend, 'Psi Noise Trend'); xlabel(app.UIAxes_PsiTrend, 'Temperature (°C)');
                
                app.GraphHandles.Initialized = true;
            else
                set(app.GraphHandles.eg_meas, 'XData', temps_C, 'YData', extracted_Eg);
                set(app.GraphHandles.bar_d, 'XData', temps_C, 'YData', noise_metric_delta);
                set(app.GraphHandles.bar_p, 'XData', temps_C, 'YData', noise_metric_psi);
            end
            
            % Dynamic Tauc labels and axis limits updates
            if power_val == 2
                ylabel(app.UIAxes_Tauc, '(\alpha \cdot E)^2 (Direct)');
            else
                ylabel(app.UIAxes_Tauc, '(\alpha \cdot E)^{1/2} (Indirect)');
            end
            
            ylim(app.UIAxes_Tauc, [0, app.DirectYLimitEditField.Value]); 
            xlim(app.UIAxes_Tauc, [1.3, 2.4]);

            
            % Dropdown refresh
            temp_labels = arrayfun(@(t) sprintf('%d °C', t), temps_C, 'UniformOutput', false);
            app.TempDropDown.Items = temp_labels;
            if ~any(strcmp(app.TempDropDown.Value, temp_labels))
                if any(strcmp(temp_labels, '20 °C')), app.TempDropDown.Value = '20 °C';
                else, app.TempDropDown.Value = temp_labels{1}; end
            end
            
            TempDropDownValueChanged(app, []);
            app.ExtractedEg = extracted_Eg; app.NoiseDelta = noise_metric_delta; app.TempsC = temps_C;
            
            drawnow limitrate;
        end

        % Value changed function: Egsearch135170eVSlider
        function Egsearch135170eVSlider_left(app, event)
            app.LiveEgSearchRange = app.Egsearch135170eVSlider.Value;
            
            % Execute calculations only if the first manual run is already completed
            if isfield(app.GraphHandles, 'Initialized') && app.GraphHandles.Initialized
                app.RunanalysisButtonPushed([]);
            end
        end

        % Value changing function: Egsearch135170eVSlider
        function Egsearch135170eVSlider_right(app, event)
            changingValue = event.Value;
            app.Egsearch135170eVSliderLabel.Text = sprintf('Search: %.2f - %.2f eV', changingValue(1), changingValue(2));
            app.LiveEgSearchRange = changingValue;
            
            % Execute calculations only if the first manual run is already completed
            if isfield(app.GraphHandles, 'Initialized') && app.GraphHandles.Initialized
                app.RunanalysisButtonPushed([]);
            end
        end

        % Value changed function: TempRangeSlider9020C
        function TempRangeSlider9020CValueChanged(app, event)
            value = app.TempRangeSlider9020C.Value;
            min_T = round(value(1) / 5) * 5; max_T = round(value(2) / 5) * 5; 
            app.TempRangeSlider9020CLabel.Text = sprintf('Temp Range: %d to %d °C', min_T, max_T);
            
            % Execute calculations only if the first manual run is already completed
            if isfield(app.GraphHandles, 'Initialized') && app.GraphHandles.Initialized
                app.RunanalysisButtonPushed([]);
            end
        end

        % Value changed function: TempDropDown
        function TempDropDownValueChanged(app, event)
            selected_temp_str = app.TempDropDown.Value; 
            idx = find(strcmp(app.TempDropDown.Items, selected_temp_str));
            
            if isempty(idx) || isempty(app.SavedOpticalData) || idx > length(app.SavedOpticalData)
                return;
            end
            
            opt_data = app.SavedOpticalData(idx);
            E_ev = opt_data.E_ev; alpha = opt_data.alpha; power_val = opt_data.power_val; c = opt_data.color;
            
            smooth_win = 5;
            if isempty(app.LiveEgSearchRange)
                app.LiveEgSearchRange = app.Egsearch135170eVSlider.Value;
            end
            min_E = app.LiveEgSearchRange(1); 
            max_E = app.LiveEgSearchRange(2);
            
            cla(app.UIAxes_TaucSingle); hold(app.UIAxes_TaucSingle, 'on'); grid(app.UIAxes_TaucSingle, 'on');
            
            if length(E_ev) > 5
                y_tauc = (alpha .* E_ev).^power_val;
                deriv = diff(smoothdata(y_tauc, 'gaussian', smooth_win)) ./ diff(E_ev);
                E_mid = (E_ev(1:end-1) + E_ev(2:end)) / 2;
                search_mask = (E_mid > min_E & E_mid < max_E); 
                
                if any(search_mask)
                    [~, max_idx_rel] = max(deriv(search_mask));
                    search_indices = find(search_mask);
                    center_idx = search_indices(max_idx_rel);
                    fit_win = (center_idx - 8) : (center_idx + 8);
                    fit_win = fit_win(fit_win > 0 & fit_win <= length(E_ev));
                    
                    p = polyfit(E_ev(fit_win), y_tauc(fit_win), 1);
                    Eg = -p(2) / p(1);
                    x_ext = linspace(Eg, E_ev(center_idx) + 0.05, 50);
                    y_ext = polyval(p, x_ext);
                    
                    plot(app.UIAxes_TaucSingle, E_ev, y_tauc, 'LineWidth', 2, 'Color', c);
                    plot(app.UIAxes_TaucSingle, x_ext, y_ext, '--', 'Color', 'k', 'LineWidth', 1.5);
                    plot(app.UIAxes_TaucSingle, Eg, 0, 'rx', 'MarkerSize', 10, 'LineWidth', 2);
                    
                    % Format chart layouts and scale Y axis correctly
                    title(app.UIAxes_TaucSingle, sprintf('Single Tauc Plot: %s (E_g = %.3f eV)', selected_temp_str, Eg));
                    xlabel(app.UIAxes_TaucSingle, 'Energy (eV)');
                    
                    if power_val == 2
                        ylabel(app.UIAxes_TaucSingle, '(\alpha \cdot E)^2 (Direct)');
                    else
                        ylabel(app.UIAxes_TaucSingle, '(\alpha \cdot E)^{1/2} (Indirect)');
                    end

                    ylim(app.UIAxes_TaucSingle, [0, app.DirectYLimitEditField.Value]);

                end
            end
        end

        % Value changing function: WavelengthSlider
        function WavelengthSliderValueChanging(app, event)
            changingValue = event.Value;
            app.Wavelength2501000nmLabel.Text = sprintf('Wavelength: %.0f - %.0f nm', changingValue(1), changingValue(2));
            app.LiveWavelengthRange = changingValue;
            
            % Execute calculations only if the first manual run is already completed
            if isfield(app.GraphHandles, 'Initialized') && app.GraphHandles.Initialized
                app.RunanalysisButtonPushed([]);
            end
        end

        % Selection changed function: BandgaptypeButtonGroup
        function BandgaptypeButtonGroupSelectionChanged(app, event)
             % Execute calculations only if the first manual run is already completed
            if isfield(app.GraphHandles, 'Initialized') && app.GraphHandles.Initialized
                app.RunanalysisButtonPushed([]);
            end        
        end

        % Value changed function: DirectYLimitEditField
        function DirectYLimitEditFieldValueChanged(app, event)
            % Execute calculations only if the first manual run is already completed
            if isfield(app.GraphHandles, 'Initialized') && app.GraphHandles.Initialized
                app.RunanalysisButtonPushed([]);
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {527, 527};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {228, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.LoadWVASEDataButton = uibutton(app.LeftPanel, 'push');
            app.LoadWVASEDataButton.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushedFcn, true);
            app.LoadWVASEDataButton.Text = 'Load WVASE Data';
            app.Lamp = uilamp(app.LeftPanel);
            app.BandgaptypeButtonGroup = uibuttongroup(app.LeftPanel);
            app.BandgaptypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @BandgaptypeButtonGroupSelectionChanged, true);
            app.BandgaptypeButtonGroup.Title = 'Band gap type';
            app.DirectButton = uiradiobutton(app.BandgaptypeButtonGroup);
            app.DirectButton.Text = 'Direct';
            app.DirectButton.Value = true;
            app.IndirectButton = uiradiobutton(app.BandgaptypeButtonGroup);
            app.IndirectButton.Text = 'Indirect';
            app.Egsearch135170eVSliderLabel = uilabel(app.LeftPanel);
            app.Egsearch135170eVSliderLabel.Text = 'Eg search: 1.35 - 1.70 eV';
            app.Egsearch135170eVSlider = uislider(app.LeftPanel, 'range');
            app.Egsearch135170eVSlider.Limits = [1 4];
            app.Egsearch135170eVSlider.ValueChangedFcn = createCallbackFcn(app, @Egsearch135170eVSlider_left, true);
            app.Egsearch135170eVSlider.ValueChangingFcn = createCallbackFcn(app, @Egsearch135170eVSlider_right, true);
            app.Egsearch135170eVSlider.Value = [1.35 1.7];
            app.TempRangeSlider9020CLabel = uilabel(app.LeftPanel);
            app.TempRangeSlider9020CLabel.Text = 'TempRangeSlider: -90:20 C';
            app.TempRangeSlider9020C = uislider(app.LeftPanel, 'range');
            app.TempRangeSlider9020C.Limits = [-110 20];
            app.TempRangeSlider9020C.MajorTicks = [-110 -70 -30 0 20];
            app.TempRangeSlider9020C.ValueChangedFcn = createCallbackFcn(app, @TempRangeSlider9020CValueChanged, true);
            app.TempRangeSlider9020C.Step = 5;
            app.TempRangeSlider9020C.Value = [-90 20];
            app.Wavelength2501000nmLabel = uilabel(app.LeftPanel);
            app.Wavelength2501000nmLabel.Text = 'Wavelength: 250:1000 nm';
            app.WavelengthSlider = uislider(app.LeftPanel, 'range');
            app.WavelengthSlider.Limits = [200 1000];
            app.WavelengthSlider.ValueChangingFcn = createCallbackFcn(app, @WavelengthSliderValueChanging, true);
            app.WavelengthSlider.Value = [250 1000];
            app.FolderStatusLabel = uilabel(app.LeftPanel);
            app.FolderStatusLabel.Text = '';
            app.DirectYMaxLabel = uilabel(app.LeftPanel);
            app.DirectYMaxLabel.Text = 'Tauc Y-limit';
            app.DirectYLimitEditField = uieditfield(app.LeftPanel, 'numeric');
            app.DirectYLimitEditField.ValueChangedFcn = createCallbackFcn(app, @DirectYLimitEditFieldValueChanged, true);
            app.DirectYLimitEditField.Value = 1200000000;
            app.RunanalysisButton = uibutton(app.LeftPanel, 'push');
            app.RunanalysisButton.ButtonPushedFcn = createCallbackFcn(app, @RunanalysisButtonPushed, true);
            app.RunanalysisButton.Text = 'Run analysis';
            app.RightPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.RightPanel);
            app.OpticalConstantsTab = uitab(app.TabGroup);
            app.OpticalConstantsTab.Title = 'Optical Constants';
            app.GridLayout2 = uigridlayout(app.OpticalConstantsTab);
            app.UIAxes_alphaLog = uiaxes(app.GridLayout2);
            title(app.UIAxes_alphaLog, 'Title')
            xlabel(app.UIAxes_alphaLog, 'X')
            ylabel(app.UIAxes_alphaLog, 'Y')
            zlabel(app.UIAxes_alphaLog, 'Z')
            app.UIAxes_alphaLin = uiaxes(app.GridLayout2);
            title(app.UIAxes_alphaLin, 'Title')
            xlabel(app.UIAxes_alphaLin, 'X')
            ylabel(app.UIAxes_alphaLin, 'Y')
            zlabel(app.UIAxes_alphaLin, 'Z')
            app.UIAxes_k = uiaxes(app.GridLayout2);
            title(app.UIAxes_k, 'Title')
            xlabel(app.UIAxes_k, 'X')
            ylabel(app.UIAxes_k, 'Y')
            zlabel(app.UIAxes_k, 'Z')
            app.UIAxes_n = uiaxes(app.GridLayout2);
            title(app.UIAxes_n, 'Title')
            xlabel(app.UIAxes_n, 'X')
            ylabel(app.UIAxes_n, 'Y')
            zlabel(app.UIAxes_n, 'Z')
            app.UIAxes_Legend = uiaxes(app.GridLayout2);
            title(app.UIAxes_Legend, 'Title')
            xlabel(app.UIAxes_Legend, 'X')
            ylabel(app.UIAxes_Legend, 'Y')
            zlabel(app.UIAxes_Legend, 'Z')
            app.WelcomePanel = uipanel(app.GridLayout2);
            app.WelcometolowtemperatureellipsometryanalyserLabel = uilabel(app.WelcomePanel);
            app.WelcometolowtemperatureellipsometryanalyserLabel.Text = 'Welcome to low temperature ellipsometry analyser!';
            app.LoadDatafolderLabel = uilabel(app.WelcomePanel);
            app.LoadDatafolderLabel.Text = '1. Load Data folder';
            app.ChooseconstraintsLabel = uilabel(app.WelcomePanel);
            app.ChooseconstraintsLabel.Text = '2. Choose constraints';
            app.RunanalysisLabel = uilabel(app.WelcomePanel);
            app.RunanalysisLabel.Text = '3. Run analysis';
            app.TaucAnalysisTab = uitab(app.TabGroup);
            app.TaucAnalysisTab.Title = 'Tauc Analysis';
            app.UIAxes_Tauc = uiaxes(app.TaucAnalysisTab);
            title(app.UIAxes_Tauc, 'Title')
            xlabel(app.UIAxes_Tauc, 'X')
            ylabel(app.UIAxes_Tauc, 'Y')
            zlabel(app.UIAxes_Tauc, 'Z')
            app.ResultsSystemNoiseTab = uitab(app.TabGroup);
            app.ResultsSystemNoiseTab.Title = 'Results & System Noise';
            app.UIAxes_Eg = uiaxes(app.ResultsSystemNoiseTab);
            title(app.UIAxes_Eg, 'Title')
            xlabel(app.UIAxes_Eg, 'X')
            ylabel(app.UIAxes_Eg, 'Y')
            zlabel(app.UIAxes_Eg, 'Z')
            app.UIAxes_TaucSingle = uiaxes(app.ResultsSystemNoiseTab);
            title(app.UIAxes_TaucSingle, 'Title')
            xlabel(app.UIAxes_TaucSingle, 'X')
            ylabel(app.UIAxes_TaucSingle, 'Y')
            zlabel(app.UIAxes_TaucSingle, 'Z')
            app.DropDownLabel = uilabel(app.ResultsSystemNoiseTab);
            app.DropDownLabel.Text = 'Drop Down';
            app.TempDropDown = uidropdown(app.ResultsSystemNoiseTab);
            app.TempDropDown.ValueChangedFcn = createCallbackFcn(app, @TempDropDownValueChanged, true);
            app.NoiseAnalysisTab = uitab(app.TabGroup);
            app.NoiseAnalysisTab.Title = 'Noise Analysis';
            app.UIAxes_DeltaOverlay = uiaxes(app.NoiseAnalysisTab);
            title(app.UIAxes_DeltaOverlay, 'Title')
            xlabel(app.UIAxes_DeltaOverlay, 'X')
            ylabel(app.UIAxes_DeltaOverlay, 'Y')
            zlabel(app.UIAxes_DeltaOverlay, 'Z')
            app.UIAxes_DeltaTrend = uiaxes(app.NoiseAnalysisTab);
            title(app.UIAxes_DeltaTrend, 'Title')
            xlabel(app.UIAxes_DeltaTrend, 'X')
            ylabel(app.UIAxes_DeltaTrend, 'Y')
            zlabel(app.UIAxes_DeltaTrend, 'Z')
            app.UIAxes_PsiOverlay = uiaxes(app.NoiseAnalysisTab);
            title(app.UIAxes_PsiOverlay, 'Title')
            xlabel(app.UIAxes_PsiOverlay, 'X')
            ylabel(app.UIAxes_PsiOverlay, 'Y')
            zlabel(app.UIAxes_PsiOverlay, 'Z')
            app.UIAxes_PsiTrend = uiaxes(app.NoiseAnalysisTab);
            title(app.UIAxes_PsiTrend, 'Title')
            xlabel(app.UIAxes_PsiTrend, 'X')
            ylabel(app.UIAxes_PsiTrend, 'Y')
            zlabel(app.UIAxes_PsiTrend, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ellipsometry_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end